classdef GUI_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LightCurtainDemoButton        matlab.ui.control.Button
        ShutdownSystemButton          matlab.ui.control.Button
        EMERGENCYSTOPButton           matlab.ui.control.StateButton
        RunSystemButton               matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        WidowJointControlTab          matlab.ui.container.Tab
        Joint4Slider                  matlab.ui.control.Slider
        Joint4SliderLabel             matlab.ui.control.Label
        Joint3Slider                  matlab.ui.control.Slider
        Joint3SliderLabel             matlab.ui.control.Label
        Joint5Slider                  matlab.ui.control.Slider
        Joint5SliderLabel             matlab.ui.control.Label
        Joint6Slider                  matlab.ui.control.Slider
        Joint6SliderLabel             matlab.ui.control.Label
        Joint2Slider                  matlab.ui.control.Slider
        Joint2SliderLabel             matlab.ui.control.Label
        Joint1Slider                  matlab.ui.control.Slider
        Joint1SliderLabel             matlab.ui.control.Label
        UR3JointControlTab            matlab.ui.container.Tab
        Joint6Slider_2                matlab.ui.control.Slider
        Joint6Slider_2Label           matlab.ui.control.Label
        Joint5Slider_2                matlab.ui.control.Slider
        Joint5Slider_2Label           matlab.ui.control.Label
        Joint4Slider_2                matlab.ui.control.Slider
        Joint4Slider_2Label           matlab.ui.control.Label
        Joint3Slider_2                matlab.ui.control.Slider
        Joint3Slider_2Label           matlab.ui.control.Label
        Joint2Slider_2                matlab.ui.control.Slider
        Joint2Slider_2Label           matlab.ui.control.Label
        Joint1Slider_2                matlab.ui.control.Slider
        Joint1Slider_2Label           matlab.ui.control.Label
        XYZCartesianControlTab        matlab.ui.container.Tab
        ZEditField_2                  matlab.ui.control.NumericEditField
        ZEditField_2Label             matlab.ui.control.Label
        YEditField_2                  matlab.ui.control.NumericEditField
        YEditField_2Label             matlab.ui.control.Label
        XEditField_2                  matlab.ui.control.NumericEditField
        XEditField_2Label             matlab.ui.control.Label
        UR3InverseKinematicsButton    matlab.ui.control.Button
        ZEditField                    matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        WidowInverseKinematicsButton  matlab.ui.control.Button
        DISENGAGEESTOPButton          matlab.ui.control.Button
        RESUMEButton                  matlab.ui.control.Button
        EStopTriggerLamp              matlab.ui.control.Lamp
        MakeLoadedUR3eMoveButton      matlab.ui.control.Button
        MakeLoadedWidowMoveButton     matlab.ui.control.Button
    end


    properties (Access = public)
        % place your class properties here
        modelWidow % Loaded Widow
        modelUR3e % Loaded UR3e
        modelUR3eGripperL
        modelUR3eGripperR
        modelWidowX250GripperL
        modelWidowX250GripperR

        qz = [0     0     0     0     0     0];

        qrWidow = deg2rad([-15, 42.3, -5.9, -6.17, 52.4, -50.3]);
        qrUR3e = deg2rad([0, -20, 40, -110, -90, 0]);

        Widowfin1 = deg2rad([-180, -14.6, 26.9, 2.18, 76.3, -24.4]);
        UR3efin2 = deg2rad([-180, -50, 90, -130, -90, 0]);

        eStop = false; % Not working
        resume = true;

        robotUpdateQ;

        valueX;
        valueY;
        valueZ;

        valueXUR3;
        valueYUR3;
        valueZUR3;

        fig;
        Workspace;

        Link1=0;
        Link2=0;
        Link3=0;
        Link4=0;
        Link5=0;
        Link6=0;
    end

    methods (Access = public)

        function updateJointConfiguration(app)
            % Define the end-effector position using the x, y, and z values
            endEffectorPosition = [app.valueX; app.valueY; app.valueZ];

            % Perform inverse kinematics to obtain the joint configuration
            currentPos = app.modelWidow.model.getpos();
            jointConfigurations = app.modelWidow.model.ikcon(transl(endEffectorPosition), currentPos);

            % Update the joint configuration in the GUI
            % app.EditField.Value = jointConfigurations(1); % Update for joint 1
            % app.EditField_2.Value = jointConfigurations(2); % Update for joint 2
            % app.EditField_3.Value = jointConfigurations(3); % Update for joint 3
            % app.EditField_4.Value = jointConfigurations(4); % Update for joint 4
            % app.EditField_5.Value = jointConfigurations(5); % Update for joint 5
            % app.EditField_6.Value = jointConfigurations(6); % Update for joint 6
        end


        function updateJointConfigurationUR3(app)
            % Define the end-effector position using the x, y, and z values
            endEffectorPosition = [app.valueX; app.valueY; app.valueZ];

            % Perform inverse kinematics to obtain the joint configuration
            currentPos = app.modelUR3e.model.getpos();
            jointConfigurations = app.modelUR3e.model.ikcon(transl(endEffectorPosition), currentPos);

            % Update the joint configuration in the GUI
            % app.EditField_7.Value = jointConfigurations(1); % Update for joint 1
            % app.EditField_8.Value = jointConfigurations(2); % Update for joint 2
            % app.EditField_9.Value = jointConfigurations(3); % Update for joint 3
            % app.EditField_10.Value = jointConfigurations(4); % Update for joint 4
            % app.EditField_11.Value = jointConfigurations(5); % Update for joint 5
            % app.EditField_12.Value = jointConfigurations(6); % Update for joint 6
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Code to run once GUI is loaded successfully
            app.fig = figure(1); % Creating figure to simulate robots
            hold on;
            axis([-1 1.5 -1.25 1.25 -0.76 1.5]);
            camlight;

            app.Workspace = CupStacker();

            % qlims = rad2deg(app.Workspace.WidowX250.model.qlim);
            % q = rad2deg(app.Workspace.WidowX250.armQ);
            %
            % app.Joint1Slider.Limits = qlims(1,:);
            % app.Joint2Slider.Limits = qlims(2,:);
            % app.Joint3Slider.Limits = qlims(3,:);
            % app.Joint4Slider.Limits = qlims(4,:);
            % app.Joint5Slider.Limits = qlims(5,:);
            % app.Joint6Slider.Limits = qlims(5,:);
            %
            % updateWidowJoint(app,q);
            %
            % qlims = rad2deg(app.Workspace.UR3e.robot.qlim);
            % q = rad2deg(app.Workspace.UR3e.armQ);
            %
            % app.Joint1Slider_2.Limits = qlims(1,:);
            % app.Joint2Slider_2.Limits = qlims(2,:);
            % app.Joint3Slider_2.Limits = qlims(3,:);
            % app.Joint4Slider_2.Limits = qlims(4,:);
            % app.Joint5Slider_2.Limits = qlims(5,:);
            % app.Joint6Slider_2.Limits = qlims(5,:);
            %
            % updateUR3Joint(app,q);

            %% Code to run once GUI is loaded successfully
            ax = gca;
            hold on;

            % Make, save and plot some robots
            app.modelUR3e = UR3e;
            app.modelUR3e.model.base = transl([-0.5,0,0.5]);
            app.modelUR3e.model.animate(app.qrUR3e);

            app.modelWidow = WidowX250;
            app.modelWidow.model.base = transl([0.5,0,0.5]);
            app.modelWidow.model.animate(app.qrWidow);

            app.modelUR3eGripperL = UR3eGripper;
            app.modelUR3eGripperR = UR3eGripper;
            app.modelUR3eGripperL.model.base = app.modelUR3e.model.fkine(app.modelUR3e.model.getpos).T * trotx(pi/2);
            app.modelUR3eGripperR.model.base = app.modelUR3e.model.fkine(app.modelUR3e.model.getpos).T * trotz(pi) * trotx(pi/2);
            app.modelUR3eGripperL.model.animate(app.modelUR3e.model.fkine(app.qrUR3e).T);
            app.modelUR3eGripperR.model.animate(app.modelUR3e.model.fkine(app.qrUR3e).T);

            app.modelWidowX250GripperL = WidowX250Gripper;
            app.modelWidowX250GripperR = WidowX250Gripper;
            app.modelWidowX250GripperL.model.base = app.modelWidow.model.fkine(app.modelWidow.model.getpos()).T * trotx(-pi/2) * troty(pi) * transl(0, 0.023, 0);
            app.modelWidowX250GripperR.model.base = app.modelWidow.model.fkine(app.modelWidow.model.getpos()).T * trotx(-pi/2) * transl(0, 0.023, 0);
            app.modelWidowX250GripperL.model.animate(app.modelWidow.model.fkine(app.qrWidow).T);
            app.modelWidowX250GripperR.model.animate(app.modelWidow.model.fkine(app.qrWidow).T);

            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end

            % Turn off this load button and enable the move button
            app.MakeLoadedWidowMoveButton.Enable = true;
            app.MakeLoadedUR3eMoveButton.Enable = true;

        end

        % Button pushed function: RunSystemButton
        function RunSystemButtonPushed(app, event)
            app.Workspace.cupPlacement();
        end

        % Button pushed function: LightCurtainDemoButton
        function LightCurtainDemoButtonPushed(app, event)
            % Importing the 3D model of a soda can
            [f, v, data] = plyread(fullfile('data', 'sodaCan.ply'), 'tri');
            canVertices = v; % Storing vertices of the soda can

            [y1,z1] = meshgrid(-1.5:0.01:1, 0.1:0.01:1.5);  %setting location of meshgrid
            x1 = zeros(size(y1)) - 1.2;
            lightCurtain1 = surf(x1,y1,z1,'FaceAlpha',0.1,'EdgeColor','none');
            hold on;

            steps = 30; % Define the number of steps for the demonstration

            xCan = -1.5; % Initialise x-coordinate for the soda can
            canHandles = []; % Initialise an array to store handles of the soda cans

            for j = 1:steps
                % Remove previously created soda cans
                if ~isempty(canHandles)
                    delete(canHandles);
                    canHandles = [];
                end

                % Create a new soda can and store its handle
                canHandle = PlaceObject(fullfile('data', 'sodaCan.ply'), [xCan, 0, 0.6]);
                canHandles = [canHandles, canHandle];

                pause(0.1); % Pause to display the movement

                xCan = xCan + 0.01; % Increment x-coordinate for the next soda can
                canVertices(:, 1) = canVertices(:, 1) + xCan; % Update vertices along x-axis
                drawnow; % Refresh the display

                % Display a warning when the soda can crosses a certain threshold
                if xCan >= -1.2
                    disp("Light Curtain has been activated");
                    app.eStop = true;
                    lightCurtain1 = surf(x1, y1, z1, 'FaceAlpha', 0.1, 'FaceColor', 'red'); % Activate a red light curtain
                    set(gcf, 'color', 'r'); % Change figure background color to red
                end
            end
            
        end

        % Value changed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonValueChanged(app, event)
            app.eStop = true;
            app.resume = false;
            app.Workspace.emergencyButton();
            % save eStopVariablesSaved -regexp ^(?!(self)$). % saves local variables, except self
        end

        % Button pushed function: DISENGAGEESTOPButton
        function DISENGAGEESTOPButtonPushed(app, event)
            app.eStop = false;
            app.Workspace.resumeButton();
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            app.resume = true;
            app.eStop = false;
            app.Workspace.resumeButton();
            % load eStopVariableSaved
        end

        % Button pushed function: ShutdownSystemButton
        function ShutdownSystemButtonPushed(app, event)
            pause(0.5);
            clc;
            clf;
            cla;
            clear;
            close all;
            closereq;
        end

        % Button pushed function: MakeLoadedWidowMoveButton
        function MakeLoadedWidowMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrWidow, app.Widowfin1, 20);
            for robotStepIndex = 1:size(qmatrix,1)
                if app.eStop == false
                    app.modelWidow.model.animate(qmatrix(robotStepIndex,:));
                    app.modelUR3eGripperL.model.animate(app.modelUR3e.model.fkine(qmatrix(robotStepIndex,:)).T);
                    app.modelUR3eGripperR.model.animate(app.modelUR3e.model.fkine(qmatrix(robotStepIndex,:)).T);
                    drawnow;
                else
                    app.Workspace.emergencyButton();
                end
            end
        end

        % Button pushed function: MakeLoadedUR3eMoveButton
        function MakeLoadedUR3eMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR3e, app.UR3efin2, 20);

            for robotStepIndex = 1:size(qmatrix,1)
                if app.eStop == false
                    app.modelUR3e.model.animate(qmatrix(robotStepIndex,:));
                    app.modelUR3eGripperL.model.animate(app.modelUR3e.model.fkine(qmatrix(robotStepIndex,:)).T);
                    app.modelUR3eGripperR.model.animate(app.modelUR3e.model.fkine(qmatrix(robotStepIndex,:)).T);
                    drawnow;
                else
                    app.Workspace.emergencyButton();
                end
            end
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link1 = deg2rad(changingValue);
            app.modelWidow.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link2 = deg2rad(changingValue);
            app.modelWidow.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link3 = deg2rad(changingValue);
            app.modelWidow.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link4 = deg2rad(changingValue);
            app.modelWidow.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link5 = deg2rad(changingValue);
            app.modelWidow.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
        end

        % Value changing function: Joint6Slider
        function Joint6SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Link6 = deg2rad(changingValue);
            app.modelWidow.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
        end

        % Value changing function: Joint1Slider_2
        function Joint1Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Link1 = deg2rad(changingValue);
            app.modelUR3e.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
        end

        % Value changing function: Joint2Slider_2
        function Joint2Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Link2 = deg2rad(changingValue);
            app.modelUR3e.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
        end

        % Value changing function: Joint3Slider_2
        function Joint3Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Link3 = deg2rad(changingValue);
            app.modelUR3e.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
        end

        % Value changing function: Joint4Slider_2
        function Joint4Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Link4 = deg2rad(changingValue);
            app.modelUR3e.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
        end

        % Value changing function: Joint5Slider_2
        function Joint5Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Link5 = deg2rad(changingValue);
            app.modelUR3e.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
        end

        % Value changing function: Joint6Slider_2
        function Joint6Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Link6 = deg2rad(changingValue);
            app.modelUR3e.model.animate([app.Link1,app.Link2,app.Link3,app.Link4,app.Link5,app.Link6]);
        end

        % Button pushed function: WidowInverseKinematicsButton
        function WidowInverseKinematicsButtonPushed(app, event)
            % Define end-effector position
            endEffectorPosition = [app.valueX; app.valueY; app.valueZ];

            % Perform inverse kinematics to obtain the joint configuration
            currentPos = app.modelWidow.model.getpos();
            jointConfigurations = app.modelWidow.model.ikcon(transl(endEffectorPosition), currentPos);

            % Update animation
            app.modelWidow.model.animate(jointConfigurations);

        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.valueX = app.XEditField.Value;
            updateJointConfiguration(app);

        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.valueY = app.YEditField.Value;
            updateJointConfiguration(app);

        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.valueZ = app.ZEditField.Value;
            updateJointConfiguration(app);

        end

        % Button pushed function: UR3InverseKinematicsButton
        function UR3InverseKinematicsButtonPushed(app, event)
            % Define end-effector position
            endEffectorPosition = [app.valueXUR3; app.valueYUR3; app.valueZUR3];

            % Perform inverse kinematics to obtain the joint configuration
            currentPos = app.modelUR3e.model.getpos();
            jointConfigurations = app.modelUR3e.model.ikcon(transl(endEffectorPosition), currentPos);

            % Update animation
            app.modelUR3e.model.animate(jointConfigurations);

        end

        % Value changed function: XEditField_2
        function XEditField_2ValueChanged(app, event)
            app.valueXUR3 = app.XEditField_2.Value;
            updateJointConfiguration(app);

        end

        % Value changed function: YEditField_2
        function YEditField_2ValueChanged(app, event)
            app.valueYUR3 = app.XEditField_2.Value;
            updateJointConfiguration(app);

        end

        % Value changed function: ZEditField_2
        function ZEditField_2ValueChanged(app, event)
            app.valueZUR3 = app.XEditField_2.Value;
            updateJointConfiguration(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MakeLoadedWidowMoveButton = uibutton(app.UIFigure, 'push');
            app.MakeLoadedWidowMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedWidowMoveButtonPushed, true);
            app.MakeLoadedWidowMoveButton.Text = 'Make Loaded Widow Move';
            app.MakeLoadedUR3eMoveButton = uibutton(app.UIFigure, 'push');
            app.MakeLoadedUR3eMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedUR3eMoveButtonPushed, true);
            app.MakeLoadedUR3eMoveButton.Text = 'Make Loaded UR3e Move';
            app.EStopTriggerLamp = uilamp(app.UIFigure);
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Text = 'RESUME';
            app.DISENGAGEESTOPButton = uibutton(app.UIFigure, 'push');
            app.DISENGAGEESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @DISENGAGEESTOPButtonPushed, true);
            app.DISENGAGEESTOPButton.Text = 'DISENGAGE E-STOP';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WidowJointControlTab = uitab(app.TabGroup);
            app.WidowJointControlTab.Title = 'Widow Joint Control';
            app.Joint1SliderLabel = uilabel(app.WidowJointControlTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.WidowJointControlTab);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.MajorTicks = [-180 0 180];
            app.Joint1Slider.MajorTickLabels = {'-180', '0', '180'};
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint1Slider.MinorTicks = 0;
            app.Joint2SliderLabel = uilabel(app.WidowJointControlTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.WidowJointControlTab);
            app.Joint2Slider.Limits = [-108 114];
            app.Joint2Slider.MajorTicks = [-108 0 114];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint2Slider.MinorTicks = 0;
            app.Joint6SliderLabel = uilabel(app.WidowJointControlTab);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.WidowJointControlTab);
            app.Joint6Slider.Limits = [-180 180];
            app.Joint6Slider.MajorTicks = [-180 0 180];
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint6SliderValueChanging, true);
            app.Joint6Slider.MinorTicks = 0;
            app.Joint5SliderLabel = uilabel(app.WidowJointControlTab);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.WidowJointControlTab);
            app.Joint5Slider.Limits = [-180 180];
            app.Joint5Slider.MajorTicks = [-180 0 180];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint5Slider.MinorTicks = 0;
            app.Joint3SliderLabel = uilabel(app.WidowJointControlTab);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.WidowJointControlTab);
            app.Joint3Slider.Limits = [-123 92];
            app.Joint3Slider.MajorTicks = [-123 0 92];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint3Slider.MinorTicks = 0;
            app.Joint4SliderLabel = uilabel(app.WidowJointControlTab);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.WidowJointControlTab);
            app.Joint4Slider.Limits = [-100 123];
            app.Joint4Slider.MajorTicks = [-100 0 123];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint4Slider.MinorTicks = 0;
            app.UR3JointControlTab = uitab(app.TabGroup);
            app.UR3JointControlTab.Title = 'UR3 Joint Control';
            app.Joint1Slider_2Label = uilabel(app.UR3JointControlTab);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.Joint1Slider_2 = uislider(app.UR3JointControlTab);
            app.Joint1Slider_2.Limits = [-360 360];
            app.Joint1Slider_2.MajorTicks = [-360 0 360];
            app.Joint1Slider_2.ValueChangingFcn = createCallbackFcn(app, @Joint1Slider_2ValueChanging, true);
            app.Joint1Slider_2.MinorTicks = [];
            app.Joint2Slider_2Label = uilabel(app.UR3JointControlTab);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.Joint2Slider_2 = uislider(app.UR3JointControlTab);
            app.Joint2Slider_2.Limits = [-360 360];
            app.Joint2Slider_2.MajorTicks = [-360 0 360];
            app.Joint2Slider_2.ValueChangingFcn = createCallbackFcn(app, @Joint2Slider_2ValueChanging, true);
            app.Joint2Slider_2.MinorTicks = [];
            app.Joint3Slider_2Label = uilabel(app.UR3JointControlTab);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.Joint3Slider_2 = uislider(app.UR3JointControlTab);
            app.Joint3Slider_2.Limits = [-360 360];
            app.Joint3Slider_2.MajorTicks = [-360 0 360];
            app.Joint3Slider_2.ValueChangingFcn = createCallbackFcn(app, @Joint3Slider_2ValueChanging, true);
            app.Joint3Slider_2.MinorTicks = [];
            app.Joint4Slider_2Label = uilabel(app.UR3JointControlTab);
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.Joint4Slider_2 = uislider(app.UR3JointControlTab);
            app.Joint4Slider_2.Limits = [-360 360];
            app.Joint4Slider_2.MajorTicks = [-360 0 360];
            app.Joint4Slider_2.ValueChangingFcn = createCallbackFcn(app, @Joint4Slider_2ValueChanging, true);
            app.Joint4Slider_2.MinorTicks = [];
            app.Joint5Slider_2Label = uilabel(app.UR3JointControlTab);
            app.Joint5Slider_2Label.Text = 'Joint 5';
            app.Joint5Slider_2 = uislider(app.UR3JointControlTab);
            app.Joint5Slider_2.Limits = [-360 360];
            app.Joint5Slider_2.MajorTicks = [-360 0 360];
            app.Joint5Slider_2.ValueChangingFcn = createCallbackFcn(app, @Joint5Slider_2ValueChanging, true);
            app.Joint5Slider_2.MinorTicks = [];
            app.Joint6Slider_2Label = uilabel(app.UR3JointControlTab);
            app.Joint6Slider_2Label.Text = 'Joint 6';
            app.Joint6Slider_2 = uislider(app.UR3JointControlTab);
            app.Joint6Slider_2.Limits = [-360 360];
            app.Joint6Slider_2.MajorTicks = [-360 0 360];
            app.Joint6Slider_2.ValueChangingFcn = createCallbackFcn(app, @Joint6Slider_2ValueChanging, true);
            app.Joint6Slider_2.MinorTicks = [];
            app.XYZCartesianControlTab = uitab(app.TabGroup);
            app.XYZCartesianControlTab.Title = 'XYZ Cartesian Control';
            app.WidowInverseKinematicsButton = uibutton(app.XYZCartesianControlTab, 'push');
            app.WidowInverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @WidowInverseKinematicsButtonPushed, true);
            app.WidowInverseKinematicsButton.Text = 'Widow Inverse Kinematics';
            app.XEditFieldLabel = uilabel(app.XYZCartesianControlTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.XYZCartesianControlTab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.XYZCartesianControlTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.XYZCartesianControlTab, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.XYZCartesianControlTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.XYZCartesianControlTab, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.UR3InverseKinematicsButton = uibutton(app.XYZCartesianControlTab, 'push');
            app.UR3InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @UR3InverseKinematicsButtonPushed, true);
            app.UR3InverseKinematicsButton.Text = 'UR3 Inverse Kinematics';
            app.XEditField_2Label = uilabel(app.XYZCartesianControlTab);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.XYZCartesianControlTab, 'numeric');
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @XEditField_2ValueChanged, true);
            app.YEditField_2Label = uilabel(app.XYZCartesianControlTab);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.XYZCartesianControlTab, 'numeric');
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @YEditField_2ValueChanged, true);
            app.ZEditField_2Label = uilabel(app.XYZCartesianControlTab);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.XYZCartesianControlTab, 'numeric');
            app.ZEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZEditField_2ValueChanged, true);
            app.RunSystemButton = uibutton(app.UIFigure, 'push');
            app.RunSystemButton.ButtonPushedFcn = createCallbackFcn(app, @RunSystemButtonPushed, true);
            app.RunSystemButton.Text = 'Run System';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'state');
            app.EMERGENCYSTOPButton.ValueChangedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonValueChanged, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.ShutdownSystemButton = uibutton(app.UIFigure, 'push');
            app.ShutdownSystemButton.ButtonPushedFcn = createCallbackFcn(app, @ShutdownSystemButtonPushed, true);
            app.ShutdownSystemButton.Text = 'Shutdown System';
            app.LightCurtainDemoButton = uibutton(app.UIFigure, 'push');
            app.LightCurtainDemoButton.ButtonPushedFcn = createCallbackFcn(app, @LightCurtainDemoButtonPushed, true);
            app.LightCurtainDemoButton.Text = 'Light Curtain Demo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end